classdef depi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TipulmodulaieiLabel   matlab.ui.control.Label
        QAMCheckBox           matlab.ui.control.CheckBox
        BPSKCheckBox          matlab.ui.control.CheckBox
        DSBSCCheckBox         matlab.ui.control.CheckBox
        ASKCheckBox           matlab.ui.control.CheckBox
        SNRdBSlider           matlab.ui.control.Slider
        SNRdBSliderLabel      matlab.ui.control.Label
        RUNButton             matlab.ui.control.Button
        FreqHzEditField       matlab.ui.control.NumericEditField
        FreqHzEditFieldLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [snrVec, berVec] = runBERsimulation(app, fc, fs, snrMax, mode)
        N     = 1e5;
        nPts  = 100;                        
        snrVec = linspace(0, snrMax, nPts); 
        berVec = zeros(size(snrVec));
        data   = randi([0 1], N, 1);
        key    = randi([0 1], N, 1);
        enc    = bitxor(data, key);

        for k = 1:length(snrVec)
            % modulatii
            switch mode
                case 'ASK'
                    tx = enc;
                case 'DSB-SC'  
                    t       = (0:N-1)'/fs;
                    carrier = cos(2*pi*fc*t);
                    tx      = (2*enc-1) .* carrier;
                case 'BPSK'
                    tx = 2*enc - 1;
                case '4-QAM'
                    idx  = bi2de(reshape(enc,2,[]).' ,'left-msb');
                    tx   = qammod(idx,4,'UnitAveragePower',true);
                otherwise
                    error('Modulatie necunoscuta');
            end

            % trecerea lui tx prin canal cu zgomot
            rx = awgn(tx, snrVec(k), 'measured');

            % demodulare + decriptare
            switch mode
                case 'ASK'
                    rec = rx > 0.5;
                case 'DSB-SC'
                    rec = (rx .* carrier) > 0;
                case 'BPSK'
                    rec = rx > 0;
                case '4-QAM'
                    recIdx = qamdemod(rx,4,'UnitAveragePower',true);
                    bitsHat = de2bi(recIdx,2,'left-msb')';
                    rec = bitsHat(:);
            end
            dec = bitxor(rec, key);

            % bit error rate
            berVec(k) = sum(data~=dec)/N;
        end
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RunButtonPushed(app, event)

   fc   = app.FreqHzEditField.Value;
    snr  = app.SNRdBSlider.Value;

    % 1) lista de modulatii
    mods = {};
    if app.ASKCheckBox.Value,    mods{end+1} = 'ASK';    end
    if app.DSBSCCheckBox.Value,  mods{end+1} = 'DSB-SC'; end
    if app.BPSKCheckBox.Value,   mods{end+1} = 'BPSK';   end
    if app.QAMCheckBox.Value,   mods{end+1} = '4-QAM';  end

    if isempty(mods)
        uialert(app.UIFigure, 'Alege cel puțin o modulație.', 'Input Error');
        return;
    end

    % 2) in cazul bld, ne trebuie fs
    fs = [];
    if any(strcmp(mods,'DSB-SC'))
        answer = inputdlg( ...
            {'Frecvența de eșantionare [Hz]:'}, ...
            'Introdu Fs', [1 35], {'40000'});
        if isempty(answer), ; end
        fs = str2double(answer{1});
        if isnan(fs) || fs < 2*fc
            uialert(app.UIFigure, ...
               sprintf('Fs trebuie să fie  ≥ %d Hz!', 2*fc), ...
               'Frecvență de eșantionare invalidă!');
            return;
        end
    end

    % 3) plotare
    colors = lines(numel(mods));
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');

    % 4) plotare
    legendEntries = cell(size(mods));
    for i = 1:numel(mods)
        mode = mods{i};
        [snrVec, berVec] = runBERsimulation(app, fc, fs, snr, mode);
        semilogy(app.UIAxes, snrVec, berVec, ...
            'LineWidth',1.5, 'Color', colors(i,:), ...
            'DisplayName', mode);
        legendEntries{i} = mode;
    end

    % 5) axe + legenda
    hold(app.UIAxes, 'off');
    grid(app.UIAxes, 'on');
    xlabel(app.UIAxes, 'BER vs SNR');
    ylabel(app.UIAxes, 'BER');
    title(app.UIAxes, 'SNR [dB]');
    legend(app.UIAxes, 'show', 'Location', 'southwest');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'BER vs SNR')
            xlabel(app.UIAxes, 'SNR [dB]')
            ylabel(app.UIAxes, 'BER')
            zlabel(app.UIAxes, 'Z')
            app.FreqHzEditFieldLabel = uilabel(app.UIFigure);
            app.FreqHzEditFieldLabel.Text = 'Freq [Hz]';
            app.FreqHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreqHzEditField.ValueDisplayFormat = '%.0f';
            app.FreqHzEditField.Value = 20000;
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.SNRdBSliderLabel = uilabel(app.UIFigure);
            app.SNRdBSliderLabel.Text = 'SNR [dB]';
            app.SNRdBSlider = uislider(app.UIFigure);
            app.SNRdBSlider.Limits = [-35 35];
            app.ASKCheckBox = uicheckbox(app.UIFigure);
            app.ASKCheckBox.Text = 'ASK';
            app.DSBSCCheckBox = uicheckbox(app.UIFigure);
            app.DSBSCCheckBox.Text = 'DSB-SC';
            app.BPSKCheckBox = uicheckbox(app.UIFigure);
            app.BPSKCheckBox.Text = 'BPSK';
            app.QAMCheckBox = uicheckbox(app.UIFigure);
            app.QAMCheckBox.Text = '4-QAM';
            app.TipulmodulaieiLabel = uilabel(app.UIFigure);
            app.TipulmodulaieiLabel.Text = 'Tipul modulației:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = depi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end